classdef Behaviour_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BehaviourToolboxUIFigure    matlab.ui.Figure
        LoadExistingAnalysisButton  matlab.ui.control.Button
        StartNewAnalysisButton      matlab.ui.control.Button
        SaveAnalysisButton          matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        SelectROIsButton            matlab.ui.control.Button
        AnalyzeButton               matlab.ui.control.Button
        ForceUpdateCheckBox         matlab.ui.control.CheckBox
        UpdateTableButton           matlab.ui.control.Button
    end


    properties (Access = public)
        Video_path
        Experiment_set % Description
        failed_analysis
        failed_loading
        splitvideo
    end

    properties (Access = private)
    end

    methods (Access = private)
    
        function enable_analysis(app)
            app.UITable.Enable = 'On';
            app.SelectROIsButton.Enable = 'On';
            app.AnalyzeButton.Enable = 'On';
            app.ForceUpdateCheckBox.Enable = 'On';
            app.SaveAnalysisButton.Enable = 'On';
            app.UpdateTableButton.Enable = 'On';
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            assignin('base', 'behaviour_GUI', app);
        end

        % Button pushed function: LoadExistingAnalysisButton
        function LoadExistingAnalysisButtonPushed(app, event)
            selection = uigetfile('Load previous analysis','*.mat');
            if any(selection)
                app.Experiment_set = load(selection);
                app.Experiment_set = app.Experiment_set.analysis;
                app.enable_analysis();
            end
            app.UpdateTableButtonPushed();
        end

        % Button pushed function: StartNewAnalysisButton
        function StartNewAnalysisButtonPushed(app, event)
            selection = uigetdir(app.Video_path, 'Select Video Folder');
            if any(selection)
                app.Video_path = [selection, '\'];                
                app.SelectROIsButton.Enable = 'On';
            end
            app.UpdateTableButtonPushed();
        end

        % Button pushed function: SaveAnalysisButton
        function SaveAnalysisButtonPushed(app, event)
            analysis = app.Experiment_set;
            uisave('analysis');
        end

        % Button pushed function: SelectROIsButton
        function SelectROIsButtonPushed(app, event)
            if isempty(app.Video_path)
                app.StartNewAnalysisButtonPushed();
            end
            [app.failed_loading, app.splitvideo] = batch_select_video_ROIs(app.Video_path, app.Experiment_set, '', '' ,'', '');
            app.Experiment_set = evalin('base','all_experiments_output');
            app.enable_analysis();
            app.UpdateTableButtonPushed();
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            app.failed_analysis = batch_measure_MIs_from_ROIs(app.Experiment_set, app.ForceUpdateCheckBox.Value);
            app.Experiment_set = evalin('base','all_experiments_output');
            app.UpdateTableButtonPushed();
        end

        % Button pushed function: UpdateTableButton
        function UpdateTableButtonPushed(app, event)
            table = {};
            for exp = 1:numel(app.Experiment_set)
                experiment = app.Experiment_set{exp};
                level = size(table, 1);
                for videotype = 1:numel(experiment.fnames)
                    files_current_type = experiment.fnames{videotype};
                    for rec = 1:numel(files_current_type)
                        recording = files_current_type{rec};
                        name = strsplit(recording,'/');

                        if ~isempty(experiment.MI{videotype}) && numel(experiment.MI) >= videotype && numel(experiment.MI{videotype}) >= rec && ~isempty(experiment.MI{videotype}{rec})
                            table{level+rec,7}(videotype) = 1;
                        else
                            table{level+rec,7}(videotype) = 0;
                        end

                        table(level+rec,1:3) = [name(end-4), name(end-3), {name{end-2}(8:15)}];
                        if contains(name(end), 'Eye')
                            table(level+rec,4) = {1};
                        else
                            table(level+rec,4) = {0};
                        end
                        if contains(name(end), 'Body')
                            table(level+rec,5) = {1};
                        else
                            table(level+rec,5) = {0};
                        end
                        if contains(name(end), 'Whisk')
                            table(level+rec,6) = {1};
                        else
                            table(level+rec,6) = {0};
                        end
                    end
                end
            end
            if ~isempty(table)
                table(:,7) = cellfun(@sum, table(:,7), 'UniformOutput', false);
            end
            app.UITable.Data = table;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BehaviourToolboxUIFigure = uifigure;
            app.BehaviourToolboxUIFigure.Name = 'Behaviour Toolbox';
            app.LoadExistingAnalysisButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.LoadExistingAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExistingAnalysisButtonPushed, true);
            app.LoadExistingAnalysisButton.Text = 'Load Existing Analysis';
            app.StartNewAnalysisButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.StartNewAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @StartNewAnalysisButtonPushed, true);
            app.StartNewAnalysisButton.Text = 'Start New Analysis';
            app.SaveAnalysisButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.SaveAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAnalysisButtonPushed, true);
            app.SaveAnalysisButton.Enable = 'off';
            app.SaveAnalysisButton.Text = 'Save Analysis';
            app.UITable = uitable(app.BehaviourToolboxUIFigure);
            app.UITable.ColumnName = {'Day'; 'Experiment'; 'Recording'; 'Eye'; 'Body'; 'Whisker'; 'MI'};
            app.UITable.RowName = {};
            app.UITable.Enable = 'off';
            app.SelectROIsButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.SelectROIsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectROIsButtonPushed, true);
            app.SelectROIsButton.Enable = 'off';
            app.SelectROIsButton.Text = 'Select ROIs';
            app.AnalyzeButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Enable = 'off';
            app.AnalyzeButton.Text = 'Analyze';
            app.ForceUpdateCheckBox = uicheckbox(app.BehaviourToolboxUIFigure);
            app.ForceUpdateCheckBox.Enable = 'off';
            app.ForceUpdateCheckBox.Text = 'Force Update';
            app.UpdateTableButton = uibutton(app.BehaviourToolboxUIFigure, 'push');
            app.UpdateTableButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateTableButtonPushed, true);
            app.UpdateTableButton.Enable = 'off';
            app.UpdateTableButton.Text = 'Update Table';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Behaviour_analysis

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BehaviourToolboxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BehaviourToolboxUIFigure)
        end
    end
end